classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CarregarImagemButton            matlab.ui.control.Button
        ModificadoLabel                 matlab.ui.control.Label
        OriginalLabel                   matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        Trabalho1FiltrosetcnicasbsicasLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        AlgoritmosTcnicasDropDown       matlab.ui.control.DropDown
        AlgoritmosTcnicasDropDownLabel  matlab.ui.control.Label
        ProcessarButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        imgOriginal %armazena a imagem carregada
    end

    methods (Access = private)

        function img2 = carregarImagemSecundaria(app)
    [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif', ...
        'Imagens (*.jpg,*.png,*.bmp,*.tif)'}, ...
        'Selecionar a segunda imagem para operação');

    if isequal(file, 0)
        uialert(app.UIFigure, 'Nenhuma imagem selecionada!', 'Erro');
        error('Operação cancelada, imagem secundária não foi selecionada.');
    end

    img2 = imread(fullfile(path, file));
end
           
       % Função para converter RGB em escala de cinza
        function out = grayscale(~,img_rgb)
            [lin, col, ~] = size(img_rgb);
            img_gray = zeros(lin, col);
            for i = 1:lin
                for j = 1:col
                    R = double(img_rgb(i,j,1));
                    G = double(img_rgb(i,j,2));
                    B = double(img_rgb(i,j,3));
                    img_gray(i,j) = 0.3 * R + 0.6 * G + 0.1 * B;
                end
            end
            img_gray = uint8(img_gray);
            out = img_gray;
        end


        function out = roberts(app, img_rgb)
            img_gray = grayscale(app, img_rgb);
            %Gx = roberts_x
            roberts_Gx = [1 0; 
                          0 -1];

            %Gy = roberts_y
            roberts_Gy = [0 1; 
                         -1 0];

            Gx = imfilter(double(img_gray), roberts_Gx,'replicate');
            Gy = imfilter(double(img_gray), roberts_Gy,'replicate');
            
            %G = raiz de Gx^2 + Gy^2
            G = sqrt(Gx.^2 + Gy.^2);
            out = uint8(G);
        end

        function out = prewitt(app, img_rgb)
            img_gray = grayscale(app, img_rgb);
            %Gx = prewitt_x
            prewitt_Gx = [-1 0 1; 
                          -1 0 1; 
                          -1 0 1];
            %Gy = prewitt_y
            prewitt_Gy = [1 1 1; 
                          0 0 0; 
                         -1 -1 -1];

            Gx = imfilter(double(img_gray), prewitt_Gx,'replicate');
            Gy = imfilter(double(img_gray), prewitt_Gy,'replicate');

            %G = raiz de Gx^2 + Gy^2 
            G = sqrt(Gx.^2 + Gy.^2);
            out = uint8(G);
        end

        function out = sobel(app, img_rgb)
            img_gray = grayscale(app, img_rgb);
            %Gx = sobel_x
            sobel_Gx = [-1 0 1; 
                        -2 0 2;
                         1 0 1];
            %Gy = sobel_y
            sobel_Gy = [-1 -2 -1;
                        0  0  0;
                        1  2  1];

            Gx = imfilter(double(img_gray), sobel_Gx,'replicate');
            Gy = imfilter(double(img_gray), sobel_Gy,'replicate');
            
            %G = raiz de Gx^2 + Gy^2
            G = sqrt(Gx.^2 + Gy.^2);
            out = uint8(G);
        end

        %transformação logoritmica
        function out = logTransform(app, img)
            % Verifica se a imagem é colorida
            if size(img,3) == 3
                img = grayscale(app, img); % Converte para escala de cinza usando sua própria função
            end

            img = double(img); % Converte para double para permitir operações matemáticas

            % Constante de escala (ajusta para 0-255)
            c = 255 / log(1 + max(img(:)));

            % Aplica a transformação logarítmica pixel a pixel
            [lin, col] = size(img);
            output = zeros(lin, col);

            for i = 1:lin
                for j = 1:col
                    r = img(i,j);
                    output(i,j) = c * log(1 + r);
                end
            end

            % Garante que o output esteja dentro do intervalo de 0 a 255
            out = uint8(output);
        end 

        %operações aritmeticas
        % C(x,y) = A(x,y) + B(x,y)
        % C(x,y) = A(x,y) - B(x,y)
        % C(x,y) = A(x,y) * B(x,y)
        % C(x,y) = A(x,y) / B(x,y)
        % A(x,y) e B(x,y) são imagens de entrada e C(x,y) é a imagem resultante
       
        function out = operacaoSoma(app, img1, img2)
            img1 = double(img1);
            img2 = double(img2);

            [lin, col, canais] = size(img1);
            out = zeros(lin, col, canais);

            for i = 1:lin
                for j = 1:col
                    for k = 1:canais
                        out(i,j,k) = img1(i,j,k) + img2(i,j,k);
                    end
                end
            end

            out = uint8(out);
        end
    

        function out = operacaoSubtracao(app, img1, img2)
            img1 = double(img1);
            img2 = double(img2);

            [lin, col, canais] = size(img1);
            out = zeros(lin, col, canais);

            for i = 1:lin
                for j = 1:col
                    for k = 1:canais
                        out(i,j,k) = img1(i,j,k) - img2(i,j,k);
                    end
                end
            end

            out = uint8(out);
        end


        function out = operacaoMultiplicacao(app, img1, img2)
            img1 = double(img1);
            img2 = double(img2);

            [lin, col, canais] = size(img1);
            out = zeros(lin, col, canais);

            for i = 1:lin
                for j = 1:col
                    for k = 1:canais
                        out(i,j,k) = img1(i,j,k) * img2(i,j,k);
                    end
                end
            end

            out = uint8(out);
        end


        function out = operacaoDivisao(app, img1, img2)
            img1 = double(img1);
            img2 = double(img2);

            [lin, col, canais] = size(img1);
            out = zeros(lin, col, canais);

            for i = 1:lin
                for j = 1:col
                    for k = 1:canais
                        if img2(i,j,k) ~= 0
                           out(i,j,k) = img1(i,j,k) / img2(i,j,k);
                        else
                           out(i,j,k) = 0; %define zer se a divisao for por zero
                        end
                    end
                end
            end

            out = uint8(out);
        end

        %histograma
        function hist = histograma(app, img)
            % Verifica se a imagem é colorida
            if size(img,3) == 3
                img = grayscale(app, img); % Usa sua função de conversão para cinza
            end

            % Inicializa o vetor histograma com zeros (256 níveis de cinza)
            hist = zeros(1, 256);

            % Percorre todos os pixels da imagem
            [lin, col] = size(img);

            for i = 1:lin
                for j = 1:col
                    intensidade = img(i,j);    % Pega o valor do pixel (0-255)
                    hist(intensidade + 1) = hist(intensidade + 1) + 1; 
                    % +1 porque o MATLAB não indexa zero
                end
            end
        end


        %equalização de histograma
        function out = equaliza_histograma(app, img)
                    % Verifica se é RGB e converte
                    if size(img, 3) == 3
                        img = grayscale(app, img);
                    end
                        img = double(img);
    
                    % 1. Calcula o histograma
                     [lin, col] = size(img);
                     total_pixels = lin * col;
                     hist = zeros(1, 256);

                        for i = 1:lin
                           for j = 1:col
                            valor = img(i, j);
                            hist(valor + 1) = hist(valor + 1) + 1; % +1 pois Matlab começa em 1
                            end
                        end

                    % 2. Calcula a função de distribuição acumulada (CDF)
                    cdf = cumsum(hist) / total_pixels;

                    % 3. Mapeamento (forma vetorizada)
                    mapa = round(cdf * 255);
                    img_eq = mapa(img + 1); % "+1" por conta do índice

                    % 4. Saída
                    out = uint8(img_eq);
        end


        % Função para aplicar limiarização
         function exemplo = limiarizacao(app,img_gray,limiar)
            [lin, col] = size(img_gray);
            %T = 128;

            img_bin = zeros(lin, col);
            for i = 1:lin
                for j = 1:col
                    if img_gray(i,j) >= limiar
                        img_bin(i,j) = 255;
                    else
                        img_bin(i,j) = 0;
                    end
                end
            end
                
            img_bin = uint8(img_bin);
            exemplo = img_bin;
           % figure;
           % imshow(img_bin);
           % title(['Imagem Binarizada (Limiar = ', num2str(T), ')']);
        end
        
        function saida_pa = pass_alt(app,img)
            % Converte imagem RGB para tons de cinza manualmente (se for colorida)
            if size(img, 3) == 3
                img = 0.2989 * double(img(:,:,1)) + ...
                      0.5870 * double(img(:,:,2)) + ...
                      0.1140 * double(img(:,:,3));
            else
                img = double(img);
            end
        
            [lin, col] = size(img);
        
            % Máscara passa-alta
            mascara = [ -1 -1 -1;
                        -1  8 -1;
                        -1 -1 -1 ];
        
            % Pré-alocar a saída
            saida_pa = zeros(lin, col);
        
            % Aplicar o filtro manualmente
            for i = 2:lin-1
                for j = 2:col-1
                    bloco = img(i-1:i+1, j-1:j+1);     % Região 3x3 ao redor do pixel
                    saida_pa(i, j) = sum(sum(bloco .* mascara));
                end
            end
        
            % Normalizar para [0, 255]
            min_val = min(saida_pa(:));
            max_val = max(saida_pa(:));
            saida_pa = (saida_pa - min_val) / (max_val - min_val) * 255;
        
            % Converte para uint8
            saida_pa = uint8(saida_pa);
        
            % Mostrar resultado
            imshow(saida_pa);
            title('Filtro Passa-Alta (Realce de Bordas)');
        
            % Informações úteis
            whos saida_pa
            ndims(saida_pa)
            class(saida_pa)
        end
        
        function output = pass_Alt_Refor(app,image)
            A = 1.5;
            % Verifica se é uma imagem em tons de cinza
            if size(image, 3) == 3
                error('A imagem deve estar em tons de cinza');
            end
        
            % Converte para double para evitar problemas de overflow
            image = double(image);
            
            % Máscara de suavização (média 3x3)
            avg_kernel = ones(3, 3) / 9;
        
            % Dimensões da imagem
            [rows, cols] = size(image);
            
            % Inicializa imagem suavizada
            smooth = zeros(rows, cols);
            
            % Aplica suavização manualmente (sem usar conv2)
            for i = 2:rows-1
                for j = 2:cols-1
                    region = image(i-1:i+1, j-1:j+1);
                    smooth(i, j) = sum(sum(region .* avg_kernel));
                end
            end
        
            % Aplica filtro de alto reforço
            output = A * image - smooth;
            
            % Normaliza para intervalo 0-255 e converte para uint8
            output = uint8(mat2gray(output) * 255);
        end
        
        function img_out = passaBaixaMedia(app,img_in)
            img_in = double(img_in);
            [lin, col] = size(img_in);
            img_out = zeros(lin, col);
        
            % Percorre imagem ignorando bordas
            for i = 2:lin-1
                for j = 2:col-1
                    vizinhanca = img_in(i-1:i+1, j-1:j+1);
                    media = sum(vizinhanca(:)) / 9;
                    img_out(i, j) = media;
                end
            end
        
            % Converte para uint8
            img_out = uint8(img_out);
        end

        function img2 = selecionarSegundaImagem(app)
           % Abre uma janela para selecionar uma imagem
           [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp', ...
            'Imagens (*.png, *.jpg, *.jpeg, *.bmp)'}, ...
            'Selecione a Segunda Imagem');

            % Verifica se o usuário cancelou
            if isequal(file, 0)
                uialert(app.UIFigure, 'Nenhuma imagem selecionada.', 'Aviso');
                img2 = []; % Retorna vazio para controle no código principal
                return;
            end

            % Lê a imagem
            img2 = imread(fullfile(path, file));

            % Verifica se o tamanho é compatível
            if ~isequal(size(app.imgOriginal), size(img2))
                uialert(app.UIFigure, 'As imagens precisam ter o mesmo tamanho.', 'Erro');
                img2 = []; % Retorna vazio para indicar erro
                return;
            end
        end
   
      end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CarregarImagemButton
        function CarregarImagemButtonPushed(app, event)
            % Abre o seletor de arquivos e permite escolher uma imagem
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif', 'Imagens (.jpg,.png, .bmp,.tif)'}, ...
                                      'Selecionar uma imagem');
        
            % Verifica se o usuário selecionou um arquivo
            if isequal(file, 0)
                disp('Nenhum arquivo selecionado.');
                return;
            end
        
            % Lê a imagem do caminho selecionado
            img = imread(fullfile(path, file));
        
            app.imgOriginal = img;

            % Define a imagem no componente uiimage
            app.Image.ImageSource = img;

            % (Opcional) Exibe uma mensagem no console
            %disp(['Imagem carregada: ', fullfile(path, file)]);
        end

        % Button pushed function: ProcessarButton
        function ProcessarButtonPushed(app, event)
            opcao = app.AlgoritmosTcnicasDropDown.Value;
            img = app.imgOriginal;

            switch opcao
                case 'Escala de Cinza'
                    aux= grayscale(app,app.Image.ImageSource);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource =aux;
                 % imshow(aux);

                case 'Operador de Roberts'
                    aux = roberts(app, img);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource = aux;
                
                case 'Operador de Prewitt'
                    aux = prewitt(app, img);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource = aux;

                case 'Operador de Sobel'
                    aux = sobel(app, img);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource = aux;

                case 'Transformação Logaritimica' %arrumar função
                    aux = logTransform(app, img);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource = aux;
            
                case 'Histograma (Escala de Cinza)' %arrumar função
                    h = histograma(app, img);
                    figure;
                    bar(0:255, h);
                    title('Histograma');
                    xlabel('Intensidade de Cinza');
                    ylabel('Quantidade de Pixels');

                    % Mostra a imagem original ao lado, se quiser
                    aux = grayscale(app, img);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource = aux;

                case 'Equalização de histograma'
                    % Verifica se a imagem é RGB e converte para cinza
                    imgGray = grayscale(app, app.imgOriginal);

                    % Calcula o histograma da imagem original
                    histOriginal = histograma(app, imgGray);

                    % Faz a equalização da imagem
                    imgEqualizada = equaliza_histograma(app, imgGray);

                    % Calcula o histograma da imagem equalizada
                    histEqualizado = histograma(app, imgEqualizada);

                    % Atualiza a imagem equalizada no app (Image_2)
                    aux = cat(3, imgEqualizada, imgEqualizada, imgEqualizada);
                    app.Image_2.ImageSource = aux;

                    % Mostra uma janela com os histogramas antes e depois
                    figure;
                    subplot(2,2,1);
                    imshow(imgGray);
                    title('Imagem Original');

                    subplot(2,2,2);
                    bar(0:255, histOriginal, 'k');
                    title('Histograma Original');
                    xlabel('Intensidade');
                    ylabel('Pixels');

                    subplot(2,2,3);
                    imshow(imgEqualizada);
                    title('Imagem Equalizada');

                    subplot(2,2,4);
                    bar(0:255, histEqualizado, 'k');
                    title('Histograma Equalizado');
                    xlabel('Intensidade');
                    ylabel('Pixels');

                case 'Limiarização'
                    prompt = {'Enter matrix size:'};
                    dlgtitle = 'Input';
                    fieldsize = [1 45];
                    %definput = {};
                    limiar = inputdlg(prompt,dlgtitle,fieldsize)

                    valor = str2num(limiar{1});

                    aux= grayscale(app,app.Image.ImageSource);
                    aux= limiarizacao(app,aux,valor);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource =aux;

                  case 'Passa-Alta básico'
                    aux = pass_alt(app,app.Image.ImageSource);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource = aux;
                    %imshow(aux);

                case 'Passa-Alta Alto reforço'
                    aux= grayscale(app,app.Image.ImageSource);
                    aux= pass_Alt_Refor(app,aux);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource =aux;
                
                case 'Passa-Baixa Média (Básico)'
                    aux= grayscale(app,app.Image.ImageSource);
                    aux= passaBaixaMedia(app,aux);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource =aux;
                
                case 'Passa-Baixa Mediana'

                case 'Operação de Soma'
                
                img2 = selecionarSegundaImagem(app);
                if isempty(img2)
                    return; % Cancela se houve erro na seleção
                end

                aux = operacaoSoma(app, app.imgOriginal, img2);
                app.Image_2.ImageSource = aux;

                % Cria uma janela para mostrar as imagens
                figure('Name', 'Operação de Soma', 'NumberTitle', 'off');

                subplot(1,3,1);
                imshow(app.imgOriginal);
                title('Imagem A');

                subplot(1,3,2);
                imshow(img2);
                title('Imagem B');

                subplot(1,3,3);
                imshow(aux);
                title('Resultado A + B');

                case 'Operação de Subtração'
                img2 = selecionarSegundaImagem(app);
                if isempty(img2)
                    return; % Cancela se houve erro na seleção
                end

                aux = operacaoSubtracao(app, app.imgOriginal, img2);
                app.Image_2.ImageSource = aux;

                subplot(1,3,1);
                imshow(app.imgOriginal);
                title('Imagem A');

                subplot(1,3,2);
                imshow(img2);
                title('Imagem B');

                subplot(1,3,3);
                imshow(aux);
                title('Resultado A - B');

                case 'Operação de Multiplicação'
                img2 = selecionarSegundaImagem(app);
                if isempty(img2)
                    return; % Cancela se houve erro na seleção
                end

                aux = operacaoMultiplicacao(app, app.imgOriginal, img2);
                app.Image_2.ImageSource = aux;

                subplot(1,3,1);
                imshow(app.imgOriginal);
                title('Imagem A');

                subplot(1,3,2);
                imshow(img2);
                title('Imagem B');

                subplot(1,3,3);
                imshow(aux);
                title('Resultado A * B');

                case 'Operação de Divisão'
                img2 = selecionarSegundaImagem(app);
                if isempty(img2)
                    return; % Cancela se houve erro na seleção
                end

                aux = operacaoDivisao(app, app.imgOriginal, img2);
                app.Image_2.ImageSource = aux;

                subplot(1,3,1);
                imshow(app.imgOriginal);
                title('Imagem A');

                subplot(1,3,2);
                imshow(img2);
                title('Imagem B');

                subplot(1,3,3);
                imshow(aux);
                title('Resultado A / B');
                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProcessarButton = uibutton(app.UIFigure, 'push');
            app.ProcessarButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessarButtonPushed, true);
            app.ProcessarButton.Text = 'Processar';
            app.AlgoritmosTcnicasDropDownLabel = uilabel(app.UIFigure);
            app.AlgoritmosTcnicasDropDownLabel.Text = 'Algoritmos/Técnicas';
            app.AlgoritmosTcnicasDropDown = uidropdown(app.UIFigure);
            app.AlgoritmosTcnicasDropDown.Items = {'Limiarização', 'Escala de Cinza', 'Passa-Alta básico', 'Passa-Alta Alto reforço', 'Passa-Baixa Média (Básico)', 'Passa-Baixa Mediana', 'Operador de Roberts', 'Operador de Prewitt', 'Operador de Sobel', 'Transformação Logaritimica', 'Operação de Soma', 'Operação de Subtração', 'Operação de Multiplicação', 'Operação de Divisão', 'Histograma (Escala de Cinza)', 'Equalização de histograma'};
            app.AlgoritmosTcnicasDropDown.Value = 'Limiarização';
            app.Image = uiimage(app.UIFigure);
            app.Trabalho1FiltrosetcnicasbsicasLabel = uilabel(app.UIFigure);
            app.Trabalho1FiltrosetcnicasbsicasLabel.Text = 'Trabalho 1 - 💡Filtros e técnicas básicas 💡';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '>';
            app.Image_2 = uiimage(app.UIFigure);
            app.OriginalLabel = uilabel(app.UIFigure);
            app.OriginalLabel.Text = 'Original';
            app.ModificadoLabel = uilabel(app.UIFigure);
            app.ModificadoLabel.Text = 'Modificado';
            app.CarregarImagemButton = uibutton(app.UIFigure, 'push');
            app.CarregarImagemButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarImagemButtonPushed, true);
            app.CarregarImagemButton.Text = 'Carregar Imagem';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end