classdef trabalhoPDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EscolherGroupButton             matlab.ui.container.ButtonGroup
        Button3                         matlab.ui.control.RadioButton
        Button2                         matlab.ui.control.RadioButton
        Button                          matlab.ui.control.RadioButton
        CarregarImagemButton            matlab.ui.control.Button
        ModificadoLabel                 matlab.ui.control.Label
        OriginalLabel                   matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        Trabalho1FiltrosetcnicasbsicasLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        AlgoritmosTcnicasDropDown       matlab.ui.control.DropDown
        AlgoritmosTcnicasDropDownLabel  matlab.ui.control.Label
        ProcessarButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        imgOriginal % armazena a imagem carregada
    end

    methods (Access = private)

        % Função para carregar imagem secundaria na hora que for fazer operações aritméticas
        % Entrada: app - estrutura da aplicação
        % Saída: img2 - imagem carregada
        % Pré-condição: Usuário deve selecionar uma imagem válida
        % Pós-condição: A imagem é carregada na memória; erro exibido em caso de cancelamento
        function img2 = carregarImagemSecundaria(app)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif', ...
            'Imagens (*.jpg,*.png,*.bmp,*.tif)'}, ...
            'Selecionar a segunda imagem para operação');

            if isequal(file, 0)
                uialert(app.UIFigure, 'Nenhuma imagem selecionada!', 'Erro');
                error('Operação cancelada, imagem secundária não foi selecionada.');
            end

            img2 = imread(fullfile(path, file));
        end
           
        % Função para converter RGB em escala de cinza
        % Entrada: img_rgb - imagem colorida RGB
        % Saída: out - imagem convertida para escala de cinza
        % Pré-condição: A imagem deve ter 3 canais (RGB)
        % Pós-condição: Retorna imagem em tons de cinza
        function out = grayscale(~, img_rgb)
            
            % Obtém as devidas dimensões da imagem RGB
            [lin, col, ~] = size(img_rgb);

            % Inicializa a matriz da imagem que vai estar em escala de cinza com zeros
            img_gray = zeros(lin, col);

            % Percorre cada pixel da imagem
            for i = 1:lin
                for j = 1:col
                    R = double(img_rgb(i,j,1)); % extrai valores do canal vermelho do pixel (i,j)
                    G = double(img_rgb(i,j,2)); % extrai valores do canal verde do pixel (i,j)
                    B = double(img_rgb(i,j,3)); % extrai valores do canal azul do pixel (i,j)

                    % Aplica a fórmula de conversão para tons de cinza com pesos perceptuais
                    img_gray(i,j) = 0.3 * R + 0.6 * G + 0.1 * B;
                end
            end

            % Faz a conversão da imagem para inteiros de 0 a 255 (uint8), e
            % retorna a imagem com escala de cinza
            img_gray = uint8(img_gray);
            out = img_gray;
        end


        % Função para aplicar limiarização
        % Entrada: img_gray - imagem em escala cinza, limiar - valor entre 0 e 255
        % Saída: out - imagem binária
        % Pré-condição: Imagem em tons de cinza
        % Pós-condição: Retorna imagem binarizada com base no limiar
         function out = limiarizacao(app, img_gray, limiar)
            % Obtém as devidas dimensões da imagem na escala de cinza
            [lin, col] = size(img_gray);

            img_bin = zeros(lin, col); % Inicializa a matriz da imagem com zeros

            % Percorre todos os pixels da imagem
            for i = 1:lin
                for j = 1:col
                    if img_gray(i,j) >= limiar % verifica se o valor do pixel é maior ou igual a limiar definida
                        img_bin(i,j) = 255;    % se for, define o pixel como branco (255)
                    else
                        img_bin(i,j) = 0;      % caso contrário, define como preto (0)
                    end
                end
            end
            
            % Converte a matriz binária para valores entre 0 e 255 (tipo uint8), e retorna a imagem atualizada
            img_bin = uint8(img_bin);
            out = img_bin;
        end


        % Função para aplicar Passa-Alta básico, focando no realce de bordas
        % Entrada: img - imagem RGB ou grayscale
        % Saída: saida_pa - imagem realçada com filtro passa-alta
        % Pré-condição: Se RGB, converte para grayscale
        % Pós-condição: Realce de bordas e detalhes
        function out = pass_alt(app, img)
            % Converte imagem RGB para escala cinza manualmente
            if size(img, 3) == 3
                img = grayscale(app, img);
            end

            % Converte a imagem para tipo double para fazer os devidos cálculos
            img = double(img);
        
            % Dimensões da imagem
            [lin, col] = size(img);
        
            % Máscara passa-alta
            mascara = [ -1 -1 -1;
                        -1  8 -1;
                        -1 -1 -1 ];
        
            % Pré-aloca a matriz de saída com zeros
            out = zeros(lin, col);
        
            % Aplicar o filtro manualmente
            for i = 2:lin-1
                for j = 2:col-1
                    bloco = img(i-1:i+1, j-1:j+1);     % Região 3x3 ao redor do pixel
                    out(i, j) = sum(sum(bloco .* mascara));
                end
            end
        
            % Normalizar para [0, 255]
            min_val = min(out(:));
            max_val = max(out(:));
            out = (out - min_val) / (max_val - min_val) * 255;
        
            % Converte para uint8
            out = uint8(out);
        end
        
        % Função para Passa-Alta Alto reforço para realçar contornos e detalhes finos
        % Entrada: image - imagem na escala de cinza
        % Saída: output - imagem com reforço de Passa-Alta Alto reforço
        % Pré-condição: Imagem na escala cinza
        % Pós-condição: Imagem com realce de contornos e detalhes finos
        function out = passa_Alta_Refor(app, image)
            A = 1.5; % fator de reforço usado para essa técnica 
        
            % Converte a imagem para tipo double para fazer os devidos cálculos
            image = double(image);
            
            % Máscara de suavização (média 3x3) 
            avg_kernel = ones(3, 3) / 9;     
        
            % Dimensões da imagem
            [lin, col] = size(image);
            
            % Inicializa imagem suavizada com zeros
            img_suavizada = zeros(lin, col);
            
            % Aplicando a suavização de forma manual 
            for i = 2:lin-1
                for j = 2:col-1
                    region = image(i-1:i+1, j-1:j+1);
                    img_suavizada(i, j) = sum(sum(region .* avg_kernel));
                end
            end
        
            % Aplica o filtro de alto reforço
            out = A * image - img_suavizada;
            
            % Normaliza para intervalo 0-255 e converte para uint8
            out = uint8(mat2gray(out) * 255);
        end
        

        % Função de Passa-Baixa Media (Básico) para suavizar com filtro de média
        % Entrada: img - imagem grayscale
        % Saída: img_out - imagem suavizada por média
        % Pré-condição: Imagem em grayscale
        % Pós-condição: Suavização com filtro de média (3x3)
        function out = passaBaixaMedia(app,image)
            
            % Converte a imagem para tipo double para fazer os devidos cálculos
            image = double(image);

            % Dimensões da imagem
            [lin, col] = size(image);

            % Pré-aloca a matriz de saída com zeros
            out = zeros(lin, col);
        
            % Percorre imagem ignorando bordas
            for i = 2:lin-1
                for j = 2:col-1
                    vizinhanca = image(i-1:i+1, j-1:j+1);
                    media = sum(vizinhanca(:)) / 9;
                    out(i, j) = media;
                end
            end
        
            % Converte para uint8
            out = uint8(out);
        end

        % Função de Passa-Baixa Mediana para redução de ruídos por mediana
        % Entrada: img - imagem grayscale
        % Saída: img_out - imagem suavizada por mediana
        % Pré-condição: Imagem em grayscale
        % Pós-condição: Redução de ruídos por mediana
        function out = passaBaixaMediana(app, img)
            
            % Converte a imagem para tipo double para fazer os devidos cálculos
            img = double(img);  

            % Dimensões da imagem
            [lin, col] = size(img);

            out = zeros(lin, col);  % Pré-aloca a matriz de saída com zeros

            % Percorre a imagem, ignorando as bordas
            for i = 2:lin-1
                for j = 2:col-1
                    vizinhanca = img(i-1:i+1, j-1:j+1);     % Região 3x3
                    mediana = median(vizinhanca(:));        % Mediana dos valores
                    out(i, j) = mediana;                    % Atribui à saída
                end
            end

            % Preserva bordas (cópia da imagem original)
            out(1, :) = img(1, :);
            out(lin, :) = img(lin, :);
            out(:, 1) = img(:, 1);
            out(:, col) = img(:, col);

            out = uint8(out);  % Converte de volta para uint8, retornando a imagem
        end


        % Função para o operador de Roberts
        % Entrada: img_rgb - imagem RGB
        % Saída: out - imagem com bordas detectadas pelo operador Roberts
        % Pré-condição: Imagem deve estar em RGB
        % Pós-condição: Bordas diagonais são destacadas
        function out = roberts(app, img_rgb)
            img_gray = grayscale(app, img_rgb);
            %Gx = roberts_x
            roberts_Gx = [0 1; 
                          -1 0];

            %Gy = roberts_y
            roberts_Gy = [1 0; 
                         0 -1];

            % imfilter realiza a convolução da imagem com as máscaras Gx e Gy, que detectam bordas nas direções horizontal e vertical
            % A opção 'replicate' é usada para replicar os valores dos pixels nas bordas,
            % garantindo que a filtragem funcione corretamente mesmo nas extremidades da imagem.
            Gx = imfilter(double(img_gray), roberts_Gx,'replicate');
            Gy = imfilter(double(img_gray), roberts_Gy,'replicate');
            
            %G = raiz de Gx^2 + Gy^2
            G = sqrt(Gx.^2 + Gy.^2);
            out = uint8(G); % Converte de volta para uint8, retornando a imagem
        end


        % Função para operador de Prewitt
        % Entrada: img_rgb - imagem RGB, direction - 'x', 'y' ou 'xy'
        % Saída: out - imagem com bordas detectadas
        % Pré-condição: Imagem RGB; direção válida ('x', 'y', 'xy')
        % Pós-condição: Aplica operador de Prewitt na direção informada
        function out = prewitt(app, img_rgb, direction) 
            img_gray = grayscale(app, img_rgb);
            %Gx = prewitt_x
            prewitt_Gx = [-1 0 1; 
                          -1 0 1; 
                          -1 0 1];
            %Gy = prewitt_y
            prewitt_Gy = [1 1 1; 
                          0 0 0; 
                         -1 -1 -1];

          % imfilter realiza a convolução da imagem com as máscaras Gx e Gy, que detectam bordas nas direções horizontal e vertical
          % A opção 'replicate' é usada para replicar os valores dos pixels nas bordas,
          % garantindo que a filtragem funcione corretamente mesmo nas extremidades da imagem.
          switch direction
            case 'x'
                G = imfilter(double(img_gray), prewitt_Gx, 'replicate');
            case 'y'
                G = imfilter(double(img_gray), prewitt_Gy, 'replicate');
            case 'xy'
                Gx = imfilter(double(img_gray), prewitt_Gx, 'replicate');
                Gy = imfilter(double(img_gray), prewitt_Gy, 'replicate');
                G = sqrt(Gx.^2 + Gy.^2);
           end

            out = uint8(G);  % Converte de volta para uint8, retornando a imagem
        end


        % Função para operador de Sobel
        % Entrada: img_rgb - imagem RGB, direction - 'x', 'y' ou 'xy'
        % Saída: out - imagem com bordas detectadas
        % Pré-condição: Imagem RGB; direção válida ('x', 'y', 'xy')
        % Pós-condição: Aplica operador de Sobel na direção informada
        function out = sobel(app, img_rgb, direction) 
            img_gray = grayscale(app, img_rgb);
            %Gx = sobel_x
            sobel_Gx = [-1 0 1; 
                        -2 0 2;
                        -1 0 1];
            %Gy = sobel_y
            sobel_Gy = [-1 -2 -1;
                        0 0 0;
                        1 2 1];
            
          % imfilter realiza a convolução da imagem com as máscaras Gx e Gy, que detectam bordas nas direções horizontal e vertical
          % A opção 'replicate' é usada para replicar os valores dos pixels nas bordas,
          % garantindo que a filtragem funcione corretamente mesmo nas extremidades da imagem.
          switch direction
            case 'x'
                G = imfilter(double(img_gray), sobel_Gx, 'replicate');
            case 'y'
                G = imfilter(double(img_gray), sobel_Gy, 'replicate');
            case 'xy'
                Gx = imfilter(double(img_gray), sobel_Gx, 'replicate');
                Gy = imfilter(double(img_gray), sobel_Gy, 'replicate');
                G = sqrt(Gx.^2 + Gy.^2);
           end

            out = uint8(G);  % Converte de volta para uint8, retornando a imagem
        end


        % Função para a Transformação Logoritmica
        % Entrada: img - imagem RGB ou em escala de cinza
        % Saída: out - imagem transformada com escala logarítmica
        % Pré-condição: Imagem válida (RGB ou grayscale)
        % Pós-condição: Realce de regiões escuras da imagem
        function out = logTransform(app, img)
            % Verifica se a imagem é colorida
            if size(img,3) == 3
                img = grayscale(app, img); % Converte para escala de cinza usando sua própria função
            end

            img = double(img); % Converte para double para permitir operações matemáticas

            % Constante de escala (ajusta para 0-255)
            c = 255 / log(1 + max(img(:)));

            % Aplica a transformação logarítmica pixel a pixel
            [lin, col] = size(img);
            output = zeros(lin, col);  % Pré-aloca a matriz de saída com zeros

            for i = 1:lin
                for j = 1:col
                    r = img(i,j);
                    output(i,j) = c * log(1 + r);
                end
            end

            out = uint8(output); % Converte de volta para uint8, retornando a imagem
        end 

       
        % Função para a Operação de Soma entre duas imagens
        % Entrada: img1, img2 - imagens de mesma dimensão
        % Saída: out - imagem resultante da soma pixel a pixel
        % Pré-condição: Imagens devem ter o mesmo tamanho
        % Pós-condição: Resultado da soma normalizada
        function out = operacaoSoma(app, img1, img2)
            img1 = double(img1);    %A(x,y)
            img2 = double(img2);    %B(x,y)

            [lin, col, canais] = size(img1);
            out = zeros(lin, col, canais);

            % Faz a operação de soma por matriz de cada imagem
            for i = 1:lin
                for j = 1:col
                    for k = 1:canais
                        out(i,j,k) = img1(i,j,k) + img2(i,j,k); %A(x,y) + B(x,y) = C(x,y)
                    end
                end
            end

            out = uint8(out); % Converte de volta para uint8, retornando a imagem
        end
    

        % Função para a Operação de Subtração entre duas imagens
        % Entrada: img1, img2 - imagens de mesma dimensão
        % Saída: out - imagem resultante da subtração pixel a pixel
        % Pré-condição: Imagens devem ter o mesmo tamanho
        % Pós-condição: Resultado da subtração normalizada
        function out = operacaoSubtracao(app, img1, img2)
            img1 = double(img1);    %A(x,y)
            img2 = double(img2);    %B(x,y)

            % Dimensões da imagem 1
            [lin, col, canais] = size(img1);

            % Pré-aloca a matriz de saída com zeros
            out = zeros(lin, col, canais); 

            % Faz a operação de subtração por matriz de cada imagem
            for i = 1:lin
                for j = 1:col
                    for k = 1:canais
                        out(i,j,k) = img1(i,j,k) - img2(i,j,k);     %A(x,y) - B(x,y) = C(x,y)
                    end
                end
            end
            out = uint8(out);   % Converte de volta para uint8, retornando a imagem
        end


        % Função para a Operação de Multiplicação entre duas imagens
        % Entrada: img1, img2 - imagens de mesma dimensão
        % Saída: out - imagem resultante da multiplicação pixel a pixel
        % Pré-condição: Imagens devem ter o mesmo tamanho
        % Pós-condição: Resultado da multiplicação normalizada
        function out = operacaoMultiplicacao(app, img1, img2)
            img1 = double(img1);    %A(x,y)
            img2 = double(img2);    %B(x,y)         

            % Dimensões da imagem 1
            [lin, col, canais] = size(img1);

            % Pré-aloca a matriz de saída com zeros
            out = zeros(lin, col, canais);

            % Faz a operação de multiplicação por matriz de cada imagem
            for i = 1:lin
                for j = 1:col
                    for k = 1:canais
                        out(i,j,k) = img1(i,j,k) * img2(i,j,k);   %A(x,y) * B(x,y) = C(x,y)
                    end
                end
            end

            out = uint8(out);   % Converte de volta para uint8, retornando a imagem
        end


        % Função para a Operação de Divisão entre duas imagens
        % Entrada: img1, img2 - imagens de mesma dimensão
        % Saída: out - imagem resultante da divisão  pixel a pixel
        % Pré-condição: Imagens devem ter o mesmo tamanho
        % Pós-condição: Resultado da divisão  normalizada
        function out = operacaoDivisao(app, img1, img2)
            img1 = double(img1);    %A(x,y)
            img2 = double(img2);    %B(x,y)

            % Dimensões da imagem 1
            [lin, col, canais] = size(img1);

            % Pré-aloca a matriz de saída com zeros
            out = zeros(lin, col, canais);

            % Faz a operação de divisão por matriz de cada imagem
            for i = 1:lin
                for j = 1:col
                    for k = 1:canais
                        if img2(i,j,k) ~= 0     % Se for diferente de zero, faz a conta
                           out(i,j,k) = img1(i,j,k) / img2(i,j,k);  %A(x,y) / B(x,y) = C(x,y)
                        else
                           out(i,j,k) = 0; % define zero se a divisao for por zero
                        end
                    end
                end
            end

            out = uint8(out);   % Converte de volta para uint8, retornando a imagem
        end


        % Função para fazer o histograma de uma imagem com escala de cinza
        % Entrada: img - imagem na escala de cinza
        % Saída: hist - vetor de contagem de intensidade de 0 a 255
        % Pré-condição: Imagem deve estar em escala de cinza
        % Pós-condição: Retorna distribuição de intensidade da imagem
        function hist = histograma(app, img)
            % Inicializa o vetor histograma com zeros (256 níveis de cinza)
            hist = zeros(1, 256);

            % Dimensões da imagem 1
            [lin, col] = size(img);

            for i = 1:lin
                for j = 1:col
                    intensidade = img(i,j);    % Pega o valor do pixel (0-255)
                    
                    hist(intensidade + 1) = hist(intensidade + 1) + 1; 
                    % +1 porque o MATLAB não indexa zero
                end
            end
        end


        % Função para fazer equalização da imagem em escala de cinza e seu histograma
        % Entrada: img - imagem RGB ou grayscale
        % Saída: out - imagem com histograma equalizado
        % Pré-condição: Imagem válida (converte RGB para grayscale, se necessário)
        % Pós-condição: Contraste da imagem melhorado
        function out = equaliza_histograma(app, img)
            % Verifica se é RGB e converte
            if size(img, 3) == 3
                img = grayscale(app, img);
            end
                img = double(img);
    
            % Calcula o histograma
            [lin, col] = size(img);
            total_pixels = lin * col;
            hist = zeros(1, 256);

            for i = 1:lin
                for j = 1:col
                    valor = img(i, j);
                    hist(valor + 1) = hist(valor + 1) + 1; % +1 pois Matlab começa em 1
                end
            end
                    
            % Calcula a função de distribuição acumulada (CDF)        
            cdf = cumsum(hist) / total_pixels;

            % Mapeamento (forma vetorizada)
            mapa = round(cdf * 255);
            img_eq = mapa(img + 1); % "+1" por conta do índice

            out = uint8(img_eq);    % Converte de volta para uint8, retornando a imagem
        end
        
        
        % Função para Selecionar a Segunda Imagem para as operações aritméticas
        % Entrada: app - estrutura da aplicação
        % Saída: img2 - imagem compatível com a imagem principal
        % Pré-condição: O usuário deve escolher uma imagem do mesmo tamanho
        % Pós-condição: Retorna a segunda imagem ou erro se cancelado ou incompatível
        function img2 = selecionarSegundaImagem(app)
           % Abre uma janela para selecionar uma imagem
           [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp', ...
            'Imagens (*.png, *.jpg, *.jpeg, *.bmp)'}, ...
            'Selecione a Segunda Imagem');

            % Verifica se o usuário cancelou
            if isequal(file, 0)
                uialert(app.UIFigure, 'Nenhuma imagem selecionada.', 'Aviso');
                img2 = []; % Retorna vazio para controle no código principal
                return;
            end

            % Lê a imagem
            img2 = imread(fullfile(path, file));

            % Verifica se o tamanho é compatível
            if ~isequal(size(app.imgOriginal), size(img2))
                uialert(app.UIFigure, 'As imagens precisam ter o mesmo tamanho.', 'Erro');
                img2 = []; % Retorna vazio para indicar erro
                return;
            end
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CarregarImagemButton
        function CarregarImagemButtonPushed(app, event)
            % Abre o seletor de arquivos e permite escolher uma imagem
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif', 'Imagens (.jpg,.png, .bmp,.tif)'}, ...
                                      'Selecionar uma imagem');
        
            % Verifica se o usuário selecionou um arquivo
            if isequal(file, 0)
                disp('Nenhum arquivo selecionado.');
                return;
            end
        
            
            img = imread(fullfile(path, file)); % Lê a imagem do caminho selecionado
        
            app.imgOriginal = img;              % Seleciona a imagem
            
            app.Image.ImageSource = img;        % Define a imagem no componente uiimage

        end

        % Button pushed function: ProcessarButton
        function ProcessarButtonPushed(app, event)
            opcao = app.AlgoritmosTcnicasDropDown.Value;
            img = app.imgOriginal;

            switch opcao
                case 'Limiarização'
                    prompt = {'Entre com o valor do limiar (0 a 255):'};
                    dlgtitle = 'Limiarização Manual';
                    fieldsize = [1 45];
                    definput = {'128'};
                    resposta = inputdlg(prompt, dlgtitle, fieldsize, definput);

                    if isempty(resposta)
                        return;  % Usuário cancelou
                    end

                    valor = str2double(resposta{1});

                    if isnan(valor) || valor < 0 || valor > 255
                        uialert(app.UIFigure, 'Insira um valor numérico entre 0 e 255.', 'Erro');
                        return;
                    end

                    aux = grayscale(app, app.Image.ImageSource);
                    aux = limiarizacao(app, aux, valor);
                    aux = cat(3, aux, aux, aux);  % Converte para RGB para exibição
                    app.Image_2.ImageSource = aux;

                case 'Escala de Cinza'
                    aux= grayscale(app,app.Image.ImageSource);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource =aux;        

                case 'Passa-Alta básico'
                    aux = pass_alt(app,app.Image.ImageSource);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource = aux;

                case 'Passa-Alta Alto reforço'
                    aux= grayscale(app,app.Image.ImageSource);
                    aux= passa_Alta_Refor(app,aux);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource = aux;
                
                case 'Passa-Baixa Média (Básico)'
                    aux= grayscale(app,app.Image.ImageSource);
                    aux= passaBaixaMedia(app,aux);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource = aux;
                
                case 'Passa-Baixa Mediana'
                    aux = grayscale(app, app.Image.ImageSource);
                    aux = passaBaixaMediana(app, aux);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource = aux;

                case 'Operador de Roberts'
                    aux = roberts(app, img);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource = aux;
                
                case 'Operador de Prewitt'
                    opcaoSelecionado = app.EscolherGroupButton.SelectedObject;
                    switch opcaoSelecionado
                        case app.Button
                            try
                                aux = prewitt(app, img, 'x');    
                                aux = cat(3, aux, aux, aux);
                                app.Image_2.ImageSource = aux;
                            catch ME
                                disp('Erro ao aplicar o filtro');
                                disp(ME.message);
                            end 
                        
                        case app.Button2
                            try
                                aux = prewitt(app, img, 'y');    
                                aux = cat(3, aux, aux, aux);
                                app.Image_2.ImageSource = aux;
                            catch ME
                                disp('Erro ao aplicar o filtro');
                                disp(ME.message);
                            end 

                        case app.Button3
                            try
                                aux = prewitt(app, img, 'xy');    
                                aux = cat(3, aux, aux, aux);
                                app.Image_2.ImageSource = aux;
                            catch ME
                                disp('Erro ao aplicar o filtro');
                                disp(ME.message);
                            end
                        end

                case 'Operador de Sobel'
                    opcaoSelecionado = app.EscolherGroupButton.SelectedObject;
                    switch opcaoSelecionado
                        case app.Button
                            try
                                aux = sobel(app, img, 'x');    
                                aux = cat(3, aux, aux, aux);
                                app.Image_2.ImageSource = aux;
                            catch ME
                                disp('Erro ao aplicar o filtro');
                                disp(ME.message);
                            end 
                        
                        case app.Button2
                            try
                                aux = sobel(app, img, 'y');    
                                aux = cat(3, aux, aux, aux);
                                app.Image_2.ImageSource = aux;
                            catch ME
                                disp('Erro ao aplicar o filtro');
                                disp(ME.message);
                            end 

                        case app.Button3
                            try
                                aux = sobel(app, img, 'xy');    
                                aux = cat(3, aux, aux, aux);
                                app.Image_2.ImageSource = aux;
                            catch ME
                                disp('Erro ao aplicar o filtro');
                                disp(ME.message);
                            end
                    end

                case 'Transformação Logaritimica' 
                    aux = logTransform(app, img);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource = aux;
            
                case 'Operação de Soma'
                    % Seleciona a segunda imagem quando apertar para processar
                    img2 = selecionarSegundaImagem(app);
                        if isempty(img2)
                            return; % Cancela se houve erro na seleção
                        end

                    aux = operacaoSoma(app, app.imgOriginal, img2);
                    app.Image_2.ImageSource = aux;

                    % Cria uma janela para mostrar as imagens
                    figure('Name', 'Operação de Soma', 'NumberTitle', 'off');

                    subplot(1,3,1);
                    imshow(app.imgOriginal);
                    title('Imagem A');

                    subplot(1,3,2);
                    imshow(img2);
                    title('Imagem B');

                    subplot(1,3,3);
                    imshow(aux);
                    title('Resultado A + B');

                case 'Operação de Subtração'
                    % Seleciona a segunda imagem quando apertar para processar
                    img2 = selecionarSegundaImagem(app);
                        if isempty(img2)
                            return; % Cancela se houve erro na seleção
                        end

                    aux = operacaoSubtracao(app, app.imgOriginal, img2);
                    app.Image_2.ImageSource = aux;

                    % Cria uma janela para mostrar as imagens
                    figure('Name', 'Operação de Subtração', 'NumberTitle', 'off');

                    subplot(1,3,1);
                    imshow(app.imgOriginal);
                    title('Imagem A');

                    subplot(1,3,2);
                    imshow(img2);
                    title('Imagem B');

                    subplot(1,3,3);
                    imshow(aux);
                    title('Resultado A - B');

                case 'Operação de Multiplicação'                                        
                    % Seleciona a segunda imagem quando apertar para processar
                    img2 = selecionarSegundaImagem(app);
                        if isempty(img2)
                            return; % Cancela se houve erro na seleção
                        end

                    aux = operacaoMultiplicacao(app, app.imgOriginal, img2);
                    app.Image_2.ImageSource = aux;

                    % Cria uma janela para mostrar as imagens
                    figure('Name', 'Operação de Multiplicação', 'NumberTitle', 'off');

                    subplot(1,3,1);
                    imshow(app.imgOriginal);
                    title('Imagem A');

                    subplot(1,3,2);
                    imshow(img2);
                    title('Imagem B');

                    subplot(1,3,3);
                    imshow(aux);
                    title('Resultado A * B');

                case 'Operação de Divisão'
                    % Seleciona a segunda imagem quando apertar para processar
                    img2 = selecionarSegundaImagem(app);
                        if isempty(img2)
                            return; % Cancela se houve erro na seleção
                        end

                    aux = operacaoDivisao(app, app.imgOriginal, img2);
                    app.Image_2.ImageSource = aux;

                    % Cria uma janela para mostrar as imagens
                    figure('Name', 'Operação de Divisão', 'NumberTitle', 'off');

                    subplot(1,3,1);
                    imshow(app.imgOriginal);
                    title('Imagem A');

                    subplot(1,3,2);
                    imshow(img2);
                    title('Imagem B');

                    subplot(1,3,3);
                    imshow(aux);
                    title('Resultado A / B');

                case 'Histograma (Escala de Cinza)'
                    % Criado o Histograma de acordo com a imagem
                    h = histograma(app, img);
                    figure;
                    bar(0:255, h);
                    title('Histograma');
                    xlabel('Intensidade de Cinza');
                    ylabel('Quantidade de Pixels');

                    aux = grayscale(app, img);
                    aux = cat(3, aux, aux, aux);
                    app.Image_2.ImageSource = aux;

                case 'Equalização de histograma'
                    % Imagem convertida para a Escala de Cinza
                    imgGray = grayscale(app, app.imgOriginal);

                    % Calcula o histograma da imagem original (opcional)
                    histOriginal = histograma(app, imgGray);

                    % Faz a equalização da imagem 
                    imgEqualizada = equaliza_histograma(app, imgGray);

                    % Calcula o histograma da imagem equalizada
                    histEqualizado = histograma(app, imgEqualizada);

                    % Atualiza a imagem equalizada na imagem modificada
                    aux = cat(3, imgEqualizada, imgEqualizada, imgEqualizada);
                    app.Image_2.ImageSource = aux;

                    % Mostra uma janela com os histogramas do antes e depois
                    figure;
                    subplot(2,2,1);
                    imshow(imgGray);
                    title('Imagem Original');

                    subplot(2,2,2);
                    bar(0:255, histOriginal, 'k');
                    title('Histograma Original');
                    xlabel('Intensidade de Cinza');
                    ylabel('Quantidade de Pixels');

                    subplot(2,2,3);
                    imshow(imgEqualizada);
                    title('Imagem Equalizada');

                    subplot(2,2,4);
                    bar(0:255, histEqualizado, 'k');
                    title('Histograma Equalizado');
                    xlabel('Intensidade de Cinza');
                    ylabel('Quantidade de Pixels');
             end
        end

        % Selection changed function: EscolherGroupButton
        function EscolherGroupButtonSelectionChanged(app, event)

        end

        % Value changed function: AlgoritmosTcnicasDropDown
        function AlgoritmosTcnicasDropDownValueChanged(app, event)
            filtro = app.AlgoritmosTcnicasDropDown.Value;
          
            app.EscolherGroupButton.Visible = "off";
            app.Button.Visible = "off";
            app.Button2.Visible = "off";
            app.Button3.Visible = "off";

            switch filtro
                case "Operador de Prewitt"
                     app.EscolherGroupButton.Visible = "on";
                     app.Button.Visible = "on";
                     app.Button2.Visible = "on";
                     app.Button3.Visible = "on";

                     app.EscolherGroupButton.Title = 'Prewitt'
                     app.Button.Text = "Prewitt Horizontal (X)";
                     app.Button2.Text = "Prewwit Vertical (Y)";
                     app.Button3.Text = "Horizontal + Vertical (X+Y)";

                case "Operador de Sobel"
                     app.EscolherGroupButton.Visible = "on";
                     app.Button.Visible = "on";
                     app.Button2.Visible = "on";
                     app.Button3.Visible = "on";

                     app.EscolherGroupButton.Title = 'Sobel'
                     app.Button.Text = "Sobel Horizontal (X)";
                     app.Button2.Text = "Sobel Vertical (Y)";
                     app.Button3.Text = "Horizontal + Vertical (X+Y)";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProcessarButton = uibutton(app.UIFigure, 'push');
            app.ProcessarButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessarButtonPushed, true);
            app.ProcessarButton.Text = 'Processar';
            app.AlgoritmosTcnicasDropDownLabel = uilabel(app.UIFigure);
            app.AlgoritmosTcnicasDropDownLabel.Text = 'Algoritmos/Técnicas';
            app.AlgoritmosTcnicasDropDown = uidropdown(app.UIFigure);
            app.AlgoritmosTcnicasDropDown.Items = {'Limiarização', 'Escala de Cinza', 'Passa-Alta básico', 'Passa-Alta Alto reforço', 'Passa-Baixa Média (Básico)', 'Passa-Baixa Mediana', 'Operador de Roberts', 'Operador de Prewitt', 'Operador de Sobel', 'Transformação Logaritimica', 'Operação de Soma', 'Operação de Subtração', 'Operação de Multiplicação', 'Operação de Divisão', 'Histograma (Escala de Cinza)', 'Equalização de histograma'};
            app.AlgoritmosTcnicasDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgoritmosTcnicasDropDownValueChanged, true);
            app.AlgoritmosTcnicasDropDown.Value = 'Limiarização';
            app.Image = uiimage(app.UIFigure);
            app.Trabalho1FiltrosetcnicasbsicasLabel = uilabel(app.UIFigure);
            app.Trabalho1FiltrosetcnicasbsicasLabel.Text = 'Trabalho 1 - 💡Filtros e técnicas básicas 💡';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '>';
            app.Image_2 = uiimage(app.UIFigure);
            app.OriginalLabel = uilabel(app.UIFigure);
            app.OriginalLabel.Text = 'Original';
            app.ModificadoLabel = uilabel(app.UIFigure);
            app.ModificadoLabel.Text = 'Modificado';
            app.CarregarImagemButton = uibutton(app.UIFigure, 'push');
            app.CarregarImagemButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarImagemButtonPushed, true);
            app.CarregarImagemButton.Text = 'Carregar Imagem';
            app.EscolherGroupButton = uibuttongroup(app.UIFigure);
            app.EscolherGroupButton.SelectionChangedFcn = createCallbackFcn(app, @EscolherGroupButtonSelectionChanged, true);
            app.EscolherGroupButton.Title = 'Button Group2';
            app.EscolherGroupButton.Visible = 'off';
            app.Button = uiradiobutton(app.EscolherGroupButton);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.EscolherGroupButton);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.EscolherGroupButton);
            app.Button3.Text = 'Button3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trabalhoPDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end